classdef wheel_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        mouse_distance                  matlab.ui.control.NumericEditField
        MouseDistancefromCentercmLabel  matlab.ui.control.Label
        hi_mat                          matlab.ui.control.CheckBox
        low_mat                         matlab.ui.control.CheckBox
        SelectInputFileButton           matlab.ui.control.Button
        indir                           matlab.ui.control.EditField
        InputDirLabel                   matlab.ui.control.Label
        SelectResDirButton              matlab.ui.control.Button
        dirtext                         matlab.ui.control.EditField
        ResDirEditFieldLabel            matlab.ui.control.Label
        max_gap                         matlab.ui.control.NumericEditField
        MaximumInactiveGapsEditFieldLabel  matlab.ui.control.Label
        min_pulse                       matlab.ui.control.NumericEditField
        MinimumActivePulsesEditFieldLabel  matlab.ui.control.Label
        frq                             matlab.ui.control.NumericEditField
        CornerFrequencyofthefinallowpassfilterHzEditFieldLabel  matlab.ui.control.Label
        thr                             matlab.ui.control.NumericEditField
        DetectionThresholdEditFieldLabel  matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    function [tl,trg,th,trghigh,slow_speed,high_speed] = wheel_speed(app,s,frq,thr,min_pulse,max_gap,fs)
        
        % NIdaq sampling rate
        
        number_of_samples_to_average = 10;
        number_of_samples_to_skip_after_jump = 40;
        
        s = double(s);
        t = 0:(1/fs):(numel(s)-1)/fs;
        
        % t = 1:numel(s);
        figure(1);
%         tiledlayout(4,1,'TileSpacing','tight');
        tiledlayout(4,1,'TileSpacing','compact');
        ax1 = nexttile;
        plot(t,s);
        grid;

%         app.indir
        
        
        % find difference in signal to detect jumps
        df = diff(s);
        dfh = find(abs(df) >2);
        ts = t(2:end);
        
        
        % plot(ts,df,'r');
        % hold off;
        % title('original signal (blue),   difference signal (red)')
        title('original signal from dac');
        xlabel('time (s)');
        ylabel('voltage  (V)');
        
        
        if 1
            % find the amount of jumps
            for i = 1:numel(dfh)
            %     dc before jump
                 l = dfh(i);
                 k = l - number_of_samples_to_average;
                 dc_before = sum(s(k:l-1))/number_of_samples_to_average;
            %     dc after jump
                 k = dfh(i) + number_of_samples_to_skip_after_jump;
                    l = k + number_of_samples_to_average ;
                 dc_after = sum(s(k:l-1))/number_of_samples_to_average;
                 dcjump(i) = (dc_after-dc_before);
            
            end
        
        else
            % find the amount of jumps
            for i = 1:numel(dfh)
            %     dc before jump
        %          l = dfh(i);
        %          k = l - number_of_samples_to_average;
                 s_before = s(dfh(i));
                 s_after = s(dfh(i)+1);
        
            %     dc after jump
        %          k = dfh(i) + number_of_samples_to_skip_after_jump;
        %             l = k + number_of_samples_to_average ;
        %          dc_after = sum(s(k:l-1))/number_of_samples_to_average;
                 dcjump(i) = (s_after - s_before);
            
            end
        end
        
        % cumulative sum of jumps
        dcacc = cumsum(dcjump);
        
        
        % process signal by removing jumps one junction at a time
        for i= 1:numel(dcacc)
             k = dfh(i)+1;
             if i <numel(dcacc)
                l = dfh(i+1);
             else
                 l = length(s);
             end
             s(k:l) = s(k:l) - dcacc(i);
        end
        
        % fs to 100 Hz
        fsl = fs/100;
        sl = resample(s,1,100);
        tl = 0:(1/fsl):(numel(sl)-1)/fsl;
        sl(end-10:end) = sl(end-10);
        ax2 = nexttile;
        plot(tl,sl)
        grid
        title('Voltage with jumps removed (downsampled to 50 Hz)');
        xlabel('time (s)');
        ylabel('voltage  (V)');
        
        
        % 
        % tps = ones(100,1);
        % % tps(40) = -10;
        % tps = sum(tps)/numel(tps);
        % 
        % slf = filtfilt(tps,1,sl);
        % plot(tl,slf,'r')
        % hold off;
        
        nsm = round(dfh/100);
        
        dfl = diff(sl);
        dfl = [0 dfl];
        dfl(end-10:end) = 0;
        ax3 = nexttile;
        plot(tl,dfl);
        narr = zeros(numel(nsm),1);
        hold on;
        
        for i  = 1:numel(nsm) -2
        
        %       figure(53)
        %       plot(dfl(nsm(i)-1:nsm(i)+5))
        
        
            a = dfl(nsm(i)-1);
            b = dfl(nsm(i));
            c = dfl(nsm(i)+1);
            d = dfl(nsm(i)+2);
            e = dfl(nsm(i)+3);
            f = dfl(nsm(i)+4);
            
            if a<b &&  b > c
                    narr(i) = nsm(i);
            elseif b<c && c>d
                    narr(i) = nsm(i)+1;
            elseif c < d && d>e
                    narr(i) =  nsm(i)+2;
            elseif d < e && e>f
                    narr(i) =  nsm(i)+3;
            else
                aa=1;
            end
        
            dfl(narr(i)) = (dfl(narr(i)-2) + dfl(narr(i)-1) + dfl(narr(i)+1)+ dfl(narr(i)+2))/4;
        end
        
        plot(tl,dfl,'g');
        
        
        narr(end-1:end) = [];
        plot(tl(narr),dfl(narr),'ko');
        grid
        ylim([-0.2 0.1]);
        
        if 0
                Fs = fsl;  % Sampling Frequency
                
                Fpass = 4;              % Passband Frequency
                Fstop = 5;              % Stopband Frequency
                Dpass = 0.057501127785;  % Passband Ripple
                Dstop = 0.0001;            % Stopband Attenuation
                dens  = 20;              % Density Factor
                
                % Calculate the order from the parameters using FIRPMORD.
                [N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
                
                % Calculate the coefficients using the FIRPM function.
                b  = firpm(N, Fo, Ao, W, {dens});
                Hd = dfilt.dffir(b);
                % Hd.Numerator = ones(16,1)/16;
                 sf = filtfilt(Hd.Numerator,1,double(dfl));
        
        else
        
                d1 = designfilt('lowpassiir','FilterOrder',6, ...
                    'HalfPowerFrequency',3,'DesignMethod','butter','SampleRate',fsl);
                
                 sf = filtfilt(d1,double(dfl));
        
        end
        
        sf(end-10:end) = 0;
        
        plot(tl,sf,'r');
        hold off;
        title('Voltage difference (slope)  with jumps removed unfiltered (blue) filtered (red)')
        xlabel('time (s)');
        ylabel('voltage  (V)');
        
        
        % sf now contains the change in voltage as a function of time
        % 4000 rotary encoder steps equals one rotation
        %  the 12-bit dac goes up to 4096
        % i.e  0 corresponds to 0V
        %  and 4096  corresponds to 5V
        
        % 4000 rotary steps  = (4000/4096)*5V   =  1 rotation
        % a V corresponds to  (a/5)*(4000/4096) rotations
        
        % sampling rate is now 100
        speed = -sf*fsl;        % volts/s
        
        % 1 rotation = 5*(4000/4096) V
        speed = speed/(5*4000/4096);  % rotations/s
        
        ax4 = nexttile;
        gn = 2*pi*app.mouse_distance.Value;
        speed = gn*speed;
        
        % plot(ts(moving_average_number_of_samples:end),gn*speed1_upd(moving_average_number_of_samples:end),'k');
        plot(tl,speed)
        hold on;
        xlim([ts(1) ts(end)]);
        title('animal speed as a function of time (blue)  filtered (black)   movement detected with gaps and short bursts removed (magenta)')
        xlabel('time (s)');
        ylabel('speed  (cm/s)');
        grid;
        
        
        d1 = designfilt('lowpassiir','FilterOrder',6, ...
            'HalfPowerFrequency',frq,'DesignMethod','butter','SampleRate',fsl);
        
         slow_speed = filtfilt(d1,double(speed));
        
        plot(tl,slow_speed,'k','LineWidth',2)
        
        line('xdata',[tl(1) tl(end)],'ydata',[thr thr]);
        
        tr = (slow_speed > thr);
        
        trg = zeros(numel(tl),1);
        
        trg(tr) = thr;
%         plot(tl,trg,'r','LineWidth',2);
        
        
        % -------------------
        dftrg = diff(trg);
        
        %  gaps
        ps = find(dftrg>0.5);
        ng = find(dftrg<-0.5);
        
        if ps(1)<ng(1) % start with drop
            ps(1) = [];
        end

        nng = numel(ng);
        nps = numel(ps);

        if nps<nng
            ng = ng(1:end-1);
        end

        wdl =  ps - ng;
        
        % remove short gaps
        mps = max_gap*fsl;
        rm = find(wdl<mps);
        for i = 1:numel(rm)
            trg(ng(rm(i)):ps(rm(i))) = thr;
            ll = line('xdata',[tl(ng(rm(i)))  tl(ps(rm(i)))],'ydata',[0.9*thr 0.9*thr ]);
            ll.Color = [0 1 0];
            ll.LineWidth = 9;
        end

%          plot(tl,trg,'g','LineWidth',2);

        % -------------------
        % remove pulses

        dftrg = diff(trg);
        
        ps = find(dftrg>0.5);
        ng = find(dftrg<-0.5);
        
        if ps(1)>ng(1) % start with rise
            ng(1) = [];
        end

        nng = numel(ng);
        nps = numel(ps);

        if nps > nng
            ps = ps(1:end-1);
        end

        wdh = ng - ps;
        
        % remove short peaks
        mps = min_pulse*fsl;
        rm = find(wdh<mps);
        for i = 1:numel(rm)
            trg(ps(rm(i)):ng(rm(i))) = 0;
            ll = line('xdata',[tl(ps(rm(i)))  tl(ng(rm(i)))],'ydata',[0.9*thr 0.9*thr]);
            ll.Color = [1 0 0];
            ll.LineWidth = 9;
        end
%           plot(tl,trg,'r','LineWidth',2);
        
        % -------------------

        plot(tl,trg,'m','LineWidth',2);
        
        hold off;
        linkaxes([ax1 ax2 ax3 ax4],'x')
        
        trghigh = repelem(trg,100);
        
        l = length(trghigh);
        th = 0:(1/fs):(l-1)/fs;
        % figure(9);
        % % plot(t,trghigh);
        % grid
        high_speed = resample(slow_speed,100,1);            
    end
        
%     =======================================================================

    function saveStatus(app,fn)
            
    h = findobj(app.UIFigure,'Type','uicheckbox');
    xarr = zeros(length(h),1);
    for i = 1:length(h)
       xarr(i) = h(i).Value;
    end
    state.checkboxes = xarr;

     h = findobj(app.UIFigure,'Type','uieditfield');
     xa = [];
     for i = 1:length(h)
        xa{i} = h(i).Value;
     end
     state.editfields = xa;

     h = findobj(app.UIFigure,'Type','uinumericeditfield');
     xa = [];
     for i = 1:length(h)
        xa{i} = h(i).Value;
     end
     state.uinumericeditfields = xa;

     h = findobj(app.UIFigure,'Type','uiradiobutton');
     xa = [];
     for i = 1:length(h)
        xa{i} = h(i).Value;
     end
     state.uiradiobuttons = xa;

     h = findobj(app.UIFigure,'Type','uiDropDown');
     xa = [];
     for i = 1:length(h)
%          fprintf('%s \n',h(i).Tag);
        xa{i} = h(i).Value;
     end
     state.dropdowns = xa;
     
   ps = app.UIFigure.Position;
   save(fn,'state','ps');

    end            

% =======================================================================
     
function loadStatus(app,fn)
%                 return
%             load('MyAppDefaultValues.mat','state');
    load(fn);

    h = findobj(app.UIFigure,'Type','uicheckbox');
    for i = 1:length(h)
        try
               h(i).Value = state.checkboxes(i);
        catch
            break;
        end
    end

     h = findobj(app.UIFigure,'Type','uieditfield');
     for i = 1:length(h)
         try
            h(i).Value = state.editfields{i};
         catch
             break;
         end
     end

     h = findobj(app.UIFigure,'Type','uinumericeditfield');
     for i = 1:length(h)
         try
            h(i).Value = state.uinumericeditfields{i};
         catch
             break;
         end
     end


     h = findobj(app.UIFigure,'Type','uiradiobutton');
      for i = 1:length(h)
          try
            h(i).Value = state.uiradiobuttons{i};
          catch
              break;
          end
      end

     h = findobj(app.UIFigure,'Type','uiDropDown');
      for i = 1:length(h)
           try
             h(i).Value = state.dropdowns{i};
           catch
               break
           end
      end

      app.UIFigure.Position = ps;


end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try     
                loadStatus(app,'gui_settings.mat');
            catch
            end

            app.ProcessButton.Enable = 'off';
            app.indir.Value = '';

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
       global FileName;
            

        d  = loadDataFile(FileName);

        fs = d.header.AcquisitionSampleRate;

        temp = d.header.AIChannelNames;
          [r,c] = size(temp);

          for idx = 1:r
                    if contains(temp(idx),'wheel')
                        break;
                    end
          end


         fn = fieldnames(d);

         [r,c] = size(fn);

         max_length = 0;
         for i = 1:r
                    if contains(fn(i),'sweep')
                           q = getfield(d,char(fn{i}));
                           ll = length(q.analogScans);
                           if ll > max_length
                               s = q.analogScans;
                                max_length = ll;                               
                           end
                    end
         end


%         frq = 0.25;    % corner frequency of the final low pass filter
%         thr = 15;       % detection threshold
%         min_pulse = 2;   % shorter active intervals are removed
%         max_gap = 3;    % shorter inactive intervals are removed
        s = s(:,idx)';   % sweep 
        
        [tl,trg,th,trghigh,speed,speedh] = app.wheel_speed(s,app.frq.Value,app.thr.Value,app.min_pulse.Value,app.max_gap.Value,fs);
        
        % tl  time vector 50 Hz
        % trg data 50 Hz
        % th  time vector 5000 Hz
        % trg high data 5000 Hz
        % speed   speed at 50 Hz 
        % speedh   speed at 500 Hz
        
        
%         figure(5)
%         plot(tl,speed);
%         grid;
%         figure(6)
%         plot(th,speedh);
%         grid;

            fn = [app.dirtext.Value '\' FileName];
            fn = fn(1:end-3);
%             fntextl = [fn '_l' '.txt'];
%             fntexth = [fn '_h' '.txt'];
            fnmatl = [fn '_l'   '.mat'];
            fnmath = [fn  '_h'  '.mat'];


             if app.low_mat.Value
                save(fnmatl,'tl','trg','speed');
            end
            if app.hi_mat.Value
                save(fnmath,'th','trghigh','speedh');
            end


%             
%             if app.low_text.Value
%                 fid = fopen(fntextl,'w');
%                 for i = i:numel(tl)
%                     fprintf(fid,'%4.3f     %2.1f   %4.3f\n',tl(i),trg(i), speed(i));
%                 end
%                 fclose(fid);
%             end
%             if app.high_text.Value
%                 fid = fopen(fntexth,'w');
%                 for i = i:numel(tl)
%                     fprintf(fid,'%4.3f     %2.1f   %4.3f\n',th(i),trghigh(i), speedh(i));
%                 end
%                 fclose(fid);
%             end


        end

        % Button pushed function: SelectResDirButton
        function SelectResDirButtonPushed(app, event)
            global dirpath resdir;
            if isempty(dirpath)
                dirpath = pwd;
            end

            resdir = [uigetdir(dirpath) '\'];

            app.dirtext.Value =resdir;

        end

        % Button pushed function: SelectInputFileButton
        function SelectInputFileButtonPushed(app, event)
             global ldirpath  FileName;

            if isempty(ldirpath)
                ldirpath = pwd;
            end

            [FileName,ldirpath,~]  =  uigetfile({'*.h5'},'Input data file',ldirpath);
            app.indir.Value = [ldirpath FileName];
                        app.ProcessButton.Enable = 'on';

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           saveStatus(app,'gui_settings.mat')
           delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.DetectionThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.DetectionThresholdEditFieldLabel.Text = 'Detection Threshold';
            app.thr = uieditfield(app.UIFigure, 'numeric');
            app.thr.Value = 15;
            app.CornerFrequencyofthefinallowpassfilterHzEditFieldLabel = uilabel(app.UIFigure);
            app.CornerFrequencyofthefinallowpassfilterHzEditFieldLabel.Text = ' Corner Frequency of the final low pass filter (Hz)';
            app.frq = uieditfield(app.UIFigure, 'numeric');
            app.frq.Value = 0.25;
            app.MinimumActivePulsesEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumActivePulsesEditFieldLabel.Text = 'Minimum Active Pulse (s)';
            app.min_pulse = uieditfield(app.UIFigure, 'numeric');
            app.min_pulse.Value = 2;
            app.MaximumInactiveGapsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumInactiveGapsEditFieldLabel.Text = 'Maximum Inactive Gap (s)';
            app.max_gap = uieditfield(app.UIFigure, 'numeric');
            app.max_gap.Value = 3;
            app.ResDirEditFieldLabel = uilabel(app.UIFigure);
            app.ResDirEditFieldLabel.Text = 'Res Dir';
            app.dirtext = uieditfield(app.UIFigure, 'text');
            app.SelectResDirButton = uibutton(app.UIFigure, 'push');
            app.SelectResDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectResDirButtonPushed, true);
            app.SelectResDirButton.Text = 'Select Res Dir';
            app.InputDirLabel = uilabel(app.UIFigure);
            app.InputDirLabel.Text = 'Input File';
            app.indir = uieditfield(app.UIFigure, 'text');
            app.SelectInputFileButton = uibutton(app.UIFigure, 'push');
            app.SelectInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFileButtonPushed, true);
            app.SelectInputFileButton.Text = 'Select Input File';
            app.low_mat = uicheckbox(app.UIFigure);
            app.low_mat.Text = 'Speed Data Low Sampling Rate Mat File';
            app.hi_mat = uicheckbox(app.UIFigure);
            app.hi_mat.Text = 'Speed Data High Sampling Rate Mat File';
            app.MouseDistancefromCentercmLabel = uilabel(app.UIFigure);
            app.MouseDistancefromCentercmLabel.Text = 'Mouse Distance from Center (cm)';
            app.mouse_distance = uieditfield(app.UIFigure, 'numeric');
            app.mouse_distance.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wheel_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end